classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DAQsListBox                  matlab.ui.control.ListBox
        DAQsListBoxLabel             matlab.ui.control.Label
        ArmDataAcquisitionPanel      matlab.ui.container.Panel
        ArmDataAcquisitionSwitch     matlab.ui.control.Switch
        TriggerControlsPanel         matlab.ui.container.Panel
        TrigModeSwitch               matlab.ui.control.Switch
        TabGroup                     matlab.ui.container.TabGroup
        ExtTrigTab                   matlab.ui.container.Tab
        ClickTrigTab                 matlab.ui.container.Tab
        TriggerButton                matlab.ui.control.StateButton
        ChannelsPanel                matlab.ui.container.Panel
        NumberofChannelsEditField    matlab.ui.control.NumericEditField
        NumberofChannelsLabel        matlab.ui.control.Label
        chanName_10                  matlab.ui.control.EditField
        chanDisplay_10               matlab.ui.control.CheckBox
        chanOn_10                    matlab.ui.control.Switch
        chanName_9                   matlab.ui.control.EditField
        chanDisplay_9                matlab.ui.control.CheckBox
        chanOn_9                     matlab.ui.control.Switch
        TrigButtonGroup              matlab.ui.container.ButtonGroup
        Button_11                    matlab.ui.control.ToggleButton
        Button_10                    matlab.ui.control.ToggleButton
        Button_9                     matlab.ui.control.ToggleButton
        Button_8                     matlab.ui.control.ToggleButton
        Button_7                     matlab.ui.control.ToggleButton
        Button_6                     matlab.ui.control.ToggleButton
        Button_5                     matlab.ui.control.ToggleButton
        Button_4                     matlab.ui.control.ToggleButton
        Button_3                     matlab.ui.control.ToggleButton
        Button_2                     matlab.ui.control.ToggleButton
        chanDisplay_8                matlab.ui.control.CheckBox
        chanDisplay_7                matlab.ui.control.CheckBox
        chanDisplay_6                matlab.ui.control.CheckBox
        chanDisplay_5                matlab.ui.control.CheckBox
        chanDisplay_4                matlab.ui.control.CheckBox
        chanDisplay_3                matlab.ui.control.CheckBox
        chanDisplay_2                matlab.ui.control.CheckBox
        chanDisplay_1                matlab.ui.control.CheckBox
        chanOn_8                     matlab.ui.control.Switch
        chanOn_7                     matlab.ui.control.Switch
        chanOn_6                     matlab.ui.control.Switch
        chanOn_5                     matlab.ui.control.Switch
        chanOn_4                     matlab.ui.control.Switch
        chanOn_3                     matlab.ui.control.Switch
        chanOn_2                     matlab.ui.control.Switch
        chanOn_1                     matlab.ui.control.Switch
        chanName_1                   matlab.ui.control.EditField
        EnableLabel                  matlab.ui.control.Label
        ChannelNameLabel             matlab.ui.control.Label
        chanName_8                   matlab.ui.control.EditField
        chanName_7                   matlab.ui.control.EditField
        chanName_6                   matlab.ui.control.EditField
        chanName_5                   matlab.ui.control.EditField
        chanName_4                   matlab.ui.control.EditField
        chanName_3                   matlab.ui.control.EditField
        chanName_2                   matlab.ui.control.EditField
        RateTimeControlsPanel        matlab.ui.container.Panel
        RecordTimesEditField         matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel    matlab.ui.control.Label
        PlotRateHzEditField          matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel     matlab.ui.control.Label
        DAQRateHzEditField           matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel      matlab.ui.control.Label
        FileSavingParametersPanel    matlab.ui.container.Panel
        FolderNote                   matlab.ui.control.Label
        FolderButton                 matlab.ui.control.Button
        ProjectFolderEditField       matlab.ui.control.EditField
        ProjectFolderEditFieldLabel  matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        mainPlot                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dn      % Names of recording DAQs (used to name fields for everything)
        s       % DAQ session 
        data    % Input data matrix
        name    % Filenames and folders
        channels% Input channel info
        rate    % Relevant information for timing/rates
        plotTimer
        lh  % Listeners for input
        newPanels
        channelPanels
    end

    methods (Access = private)
    
        function GrabData1(app,event,info)
            %Append matrices with new data
            app.data.time = [app.data.time;info.TimeStamps];
            app.data.data = [app.data.data;info.Data];
            % trim to rolling buffer size
            if length(app.data.time) >= app.rate.buffer
                app.data.time = app.data.time(end-app.rate.buffer+1:end);
                app.data.data = app.data.data(end-app.rate.buffer+1:end,:);
            end
        end
        
    
        function plotTimerUpdateFcn(app,src,event)
            %Update Plots
            if ~isempty(app.data.time)
                plot(app.mainPlot, app.data.time, app.data.data)
            end
        end
    

        function getChannelActiveState(app)
            % Get on switches by finding fields with "chanOn" in name
            fd = fieldnames(app);
            onswitches = cellfun(@(x) ~isempty(x), strfind(fd, 'chanOn'));
            is_onswitch = find(onswitches);
            % Get value of all switches, save to struct
            values = arrayfun(@(x) app.(fd{x}).Value, is_onswitch, 'UniformOutput', false);
            app.channels.active = ismember(values, 'On');
        end

        function createChannelPanel(app, index, numInit)
            % Create panel 
            app.channelPanels.(app.dn{index}) = uipanel(app.UIFigure, ...
                'Title', [app.dn{index}, ' channels'], ...
                'Position', [45 + (215 + 10)*(index-1), 0, 215, 400], ...
                'Scrollable', 'on');
            % Create "Number of channels" numeric field
            app.channels.(app.dn{index}).NumberofChannelsEditField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'numeric', ...
                'Position', [135,355,31,22], ...
                'Value', numInit, ...
                'ValueChangedFcn', createCallbackFcn(app, @ChangeNumChannelsFcn, true), ...
                'Tag', num2str(index));
            % Create trigger selection button group
            height = 25*numInit + 10 + 2*app.channels.trigOffset;
            app.channels.(app.dn{index}).TrigButtonGroup = uibuttongroup(app.channelPanels.(app.dn{index}), ...
                'Position', [177, 400-height-34, 25, height], 'Title', 'Trig', 'FontSize', 10, ...
                'AutoResizeChildren', 'off');
            % Create labels
            app.channels.(app.dn{index}).NumberofChannelsEditFieldLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Number of Channels:', 'Position', [9,357,119,22]);
            app.channels.(app.dn{index}).EnableLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Enable', 'Position', [22,338,42,22]);
            app.channels.(app.dn{index}).ChannelNameLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Channel Name', 'Position', [90,338,85,22]);
            % Create each channel
            for i = 1:numInit
                app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                    'Items', {'Off', 'On'}, 'Value', 'On', ...
                    'Position', [25, 328 - 25*(i-1), 23, 10]);
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 321 - 25*(i-1), 25, 25], ...
                    'Value', 1, 'Text', '');
                app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                    'text', 'Value', ['ch',num2str(i)], ...
                    'Position', [102, 321 - 25*(i-1), 69, 20]);
                app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(numInit-i), 16, 16], 'Text', '');
            end
            % Note how many channels
            app.channels.(app.dn{index}).howMany = numInit;
        end

        % Function to manually move trigger select buttons vertically 
        function resizeTrigButtons(app, panel, ncurrent, ntotal)
            for i = 1:ncurrent
                app.channels.(panel).(['toggleButton_',num2str(i)]).Position(2) = app.channels.trigOffset+25*(ntotal-i);
            end
        end


        function stopRecordingReset(app)
            % TODO: Set up as loop over each DAQ
            %Delete listeners
            delete(app.lh)
            %Stop sessions
            stop(app.s)
            %Stop Timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            % Write data to file
            save(app.name.filename, app.data, app.name.chanNames)
            
            %Reset GUI and System
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            app.ProjectFolderEditField.Enable = 'On';
            app.FilenameEditField.Enable = 'On';
            app.FolderButton.Enable = 'On';
            app.DAQRateHzEditField.Enable = 'On';
            app.PlotRateHzEditField.Enable = 'On';
            app.RecordTimesEditField.Enable = 'On';
%             for i = 1:app.channels.howmany
%                 app.(app.channels)
            app.chanOn_1.Enable = 'On';
            app.chanOn_2.Enable = 'On';
            app.chanOn_3.Enable = 'On';
            app.chanOn_4.Enable = 'On';
            app.chanOn_5.Enable = 'On';
            app.chanOn_6.Enable = 'On';
            app.chanOn_7.Enable = 'On';
            app.chanOn_8.Enable = 'On';
            %Clear out, re-declare matrices and indices
            app.data.time = [];
            app.data.data = [];
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Positioning constants
            app.channels.trigOffset = 8; %px

            % Populate DAQs list box based on active and connected DAQs
            % Likely use "daqlist", grab deviceIDs
            app.DAQsListBox.Items = {'Dev1', 'Dev2', 'Dev3', 'Dev4'};
            % For the first connected+active DAQ, run channel panel creation
            app.DAQsListBox.Value = {'Dev1'};
            app.dn = app.DAQsListBox.Items(1);
            createChannelPanel(app, 1, 10);
            app.channels.indexAvailable = [2];

            
            % Get handles for all channel on switches (useful to reduce copypaste)
            fd = fieldnames(app);
            onSwitches = find(cellfun(@(x) ~isempty(x), strfind(fd, 'chanOn')));
            app.channels.switchHandles = arrayfun(@(x) fd{x}, onSwitches, 'UniformOutput', false);
            % Get handles for channel names
            chanNames = find(cellfun(@(x) ~isempty(x), strfind(fd, 'chanName')));
            app.channels.nameHandles = arrayfun(@(x) fd{x}, chanNames, 'UniformOutput', false);
            app.channels.howMany = length(onSwitches);
            
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            dialogTitle = 'Select directory for saving data';
            folderName = uigetdir(pwd,dialogTitle);
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                %Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                % Start up and preallocate DAQ 
%                 daqreset;
                %Declare Rates 
                app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
                app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
                app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
                app.rate.buffer = app.rate.plotLength * app.rate.DAQ; %(samples)
                %Predeclare data
                % TODO: actually preallocate as zeros to max length
                app.data.time = [];
                app.data.data = [];
                % Get which channels are active
                getChannelActiveState(app)
                
                % Change looks of data acquisition switch
                app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
                app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
                
                %Make folder in chosen directory for today's date (if not already there)
                app.name.projFolder = app.ProjectFolderEditField.Value;
                app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
                if ~exist(app.name.path)
                    mkdir(app.name.projFolder,datestr(now,'yyyy_mm_dd'))
                end

                %Initialize filename of this test
                if ~isempty(app.FilenameEditField.Value)
                    app.name.filename = strcat(app.FilenameEditField.Value, '.mat');
                else
                    app.name.filename = strcat('testWithNoName', datestr(now,'mm_dd_yy_HH_MM_SS'),'.mat');
                end
                % Increment filename with numbers if same file already exists
                if exist(fullfile(app.name.path, app.name.filename))
                    % Add number if no number (dumb hard-indexed method)
                    if app.name.filename(end-5)~='_'
                        app.name.filename = strcat(app.name.filename(1:end-4), '_1', app.name.filename(end-3:end));
                        app.FilenameEditField.Value = app.name.filename(1:end-4);
                    % Otherwise grab and increment number
                    else
                        number = str2double(app.name.filename(end-4));
                        app.name.filename = strcat(app.name.filename(1:end-5), num2str(number+1), app.name.filename(end-3:end));
                        app.FilenameEditField.Value = app.name.filename(1:end-4);
                    end
                end
                
                % Get column names of active channels, add delimiter between
                app.name.chanNames = cell(1, app.channels.howMany);
                for i = 1:app.channels.howMany
                    app.name.chanNames{i} = app.(app.channels.nameHandles{i}).Value;
                end
                app.name.chanNames = [{'time'}, app.name.chanNames(app.channels.active)];
                

                %CREATE DAQ SESSION 1 (Voltage in)
                app.s = daq('ni');
                app.rate.DAQ = app.DAQRateHzEditField.Value;
                app.s.Rate = app.rate.DAQ;
                % Create channels
                % TODO: With actual DAQ set up new system. Hard to say if
                % it'll allow s1.addAnalogInputChannel as opposed to addAnalogInputChannel(s1, ...)
                % https://www.mathworks.com/help/daq/transition-your-code-from-session-to-dataacquisition-interface.html
                app.s.addAnalogInputChannel('Dev1', [find(app.channels.active)-1], 'Voltage');
                %Create input/output listeners
                app.lh = app.s.addlistener('DataAvailable',@app.GrabData1);                
                
                %Create Plotting Timer
                app.rate.plot = app.PlotRateHzEditField.Value;
                app.plotTimer = timer('TimerFcn',{@app.plotTimerUpdateFcn},...
                    'ExecutionMode','fixedRate','busymode','queue',...
                    'Period',1/app.rate.plot,'Name','timer-1');
                start(app.plotTimer);
                
                % Disable fields that shouldn't change during recording
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                app.DAQRateHzEditField.Enable = 'Off';
                app.PlotRateHzEditField.Enable = 'Off';
                app.RecordTimesEditField.Enable = 'Off';
                for i = 1:app.channels.howMany
                    app.(app.channels.switchHandles{i}).Enable = 'Off';
                end
                
                % Start main session
                startBackground(app.s)
           
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end

            % Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Stop sessions, catch errors in case they didn't exist or were already killed
            try
                stop(app.s)
            catch
            end
            %Delete Listeners
            delete(app.lh)
            %Stop timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            
            delete(app)
        end

        % Value changed function: TrigModeSwitch
        function TrigModeChangedFcn(app, event)
            value = app.TrigModeSwitch.Value;
            app.TabGroup.SelectedTab = app.(value);
            if strcmp(value, 'ExtTrigTab')
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'on';
                end
            else
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'off';
                end
            end
        end

        % Value changed function: NumberofChannelsEditField
        function ChangeNumChannelsFcn(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Tag);
            % If new # of channels less than current, remove some channels
            if value < app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-34, 25, height];
                % TODO: Make actual references to channel panel dimensions
                % (rather than hard-coded 400 constant)
                % Loop over channels to remove
                for i = (value+1):app.channels.(app.dn{index}).howMany
                    delete(app.channels.(app.dn{index}).(['chanOn_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanName_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]))
                end
                resizeTrigButtons(app, app.dn{index}, value, value)
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
                
            % If new number more than current, add some channels
            elseif value > app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-34, 25, height];
                resizeTrigButtons(app, app.dn{index}, app.channels.(app.dn{index}).howMany, value)
                % Loop over channels to create
                for i = (app.channels.(app.dn{index}).howMany+1):value
                    app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                        'Items', {'Off', 'On'}, 'Value', 'On', ...
                        'Position', [25, 328 - 25*(i-1), 23, 10]);
                    app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                        'Position', [78, 321 - 25*(i-1), 25, 25], ...
                        'Value', 1, 'Text', '');
                    app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                        'text', 'Value', ['ch',num2str(i)], ...
                        'Position', [102, 321 - 25*(i-1), 69, 20]);
                    app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(value-i), 16, 16], 'Text', '');
                end
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
            end
            % If same number of channels, do nothing
        end

        % Value changed function: DAQsListBox
        function NewDAQFcn(app, event)
            % Get removed DAQs, call remove channel function on them
            removedDAQs = event.PreviousValue(~contains(event.PreviousValue, event.Value));
            for i = 1:length(removedDAQs)
                % Delete
                delete(app.channelPanels.(removedDAQs{i}))
                % clear out to allow new boxes to take this location
                index = find(strcmp(app.dn, removedDAQs(i)));
                app.dn{index} = '';
                app.channels.indexAvailable(end+1) = index;
            end
            
            % Get new DAQs, call create channel function on them
            newDAQs = event.Value(~contains(event.Value, event.PreviousValue));
            for i = 1:length(newDAQs)
                % If adding this channel panel to end
                if length(app.channels.indexAvailable) == 1
                    index = app.channels.indexAvailable(1);
                    app.channels.indexAvailable = app.channels.indexAvailable + 1;
                    app.dn{end+1} = newDAQs{i};
                % If putting in open middle slot
                else
                    sortAvailable = sort(app.channels.indexAvailable);
                    index = sortAvailable(1);
                    app.channels.indexAvailable(app.channels.indexAvailable==index) = [];
                    app.dn{index} = newDAQs{i};
                end
                % Create panel
                createChannelPanel(app, index, 10);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 10000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 2;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.ChannelsPanel = uipanel(app.UIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.chanName_2 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_2.Value = 'ch1';
            app.chanName_3 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_3.Value = 'ch2';
            app.chanName_4 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_4.Value = 'ch3';
            app.chanName_5 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_5.Value = 'ch4';
            app.chanName_6 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_6.Value = 'ch5';
            app.chanName_7 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_7.Value = 'ch6';
            app.chanName_8 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_8.Value = 'ch7';
            app.ChannelNameLabel = uilabel(app.ChannelsPanel);
            app.ChannelNameLabel.Text = 'Channel Name';
            app.EnableLabel = uilabel(app.ChannelsPanel);
            app.EnableLabel.Text = 'Enable';
            app.chanName_1 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_1.Value = 'ch0';
            app.chanOn_1 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_1.Value = 'On';
            app.chanOn_2 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_2.Value = 'On';
            app.chanOn_3 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_3.Value = 'On';
            app.chanOn_4 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_4.Value = 'On';
            app.chanOn_5 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_5.Value = 'On';
            app.chanOn_6 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_6.Value = 'On';
            app.chanOn_7 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_7.Value = 'On';
            app.chanOn_8 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_8.Value = 'On';
            app.chanDisplay_1 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_1.Text = '';
            app.chanDisplay_1.Value = true;
            app.chanDisplay_2 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_2.Text = '';
            app.chanDisplay_2.Value = true;
            app.chanDisplay_3 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_3.Text = '';
            app.chanDisplay_3.Value = true;
            app.chanDisplay_4 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_4.Text = '';
            app.chanDisplay_4.Value = true;
            app.chanDisplay_5 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_5.Text = '';
            app.chanDisplay_5.Value = true;
            app.chanDisplay_6 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_6.Text = '';
            app.chanDisplay_6.Value = true;
            app.chanDisplay_7 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_7.Text = '';
            app.chanDisplay_7.Value = true;
            app.chanDisplay_8 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_8.Text = '';
            app.chanDisplay_8.Value = true;
            app.TrigButtonGroup = uibuttongroup(app.ChannelsPanel);
            app.TrigButtonGroup.Title = 'Trig';
            app.Button_2 = uitogglebutton(app.TrigButtonGroup);
            app.Button_2.Text = '';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.TrigButtonGroup);
            app.Button_3.Text = '';
            app.Button_4 = uitogglebutton(app.TrigButtonGroup);
            app.Button_4.Text = '';
            app.Button_5 = uitogglebutton(app.TrigButtonGroup);
            app.Button_5.Text = '';
            app.Button_6 = uitogglebutton(app.TrigButtonGroup);
            app.Button_6.Text = '';
            app.Button_7 = uitogglebutton(app.TrigButtonGroup);
            app.Button_7.Text = '';
            app.Button_8 = uitogglebutton(app.TrigButtonGroup);
            app.Button_8.Text = '';
            app.Button_9 = uitogglebutton(app.TrigButtonGroup);
            app.Button_9.Text = '';
            app.Button_10 = uitogglebutton(app.TrigButtonGroup);
            app.Button_10.Text = '';
            app.Button_11 = uitogglebutton(app.TrigButtonGroup);
            app.Button_11.Text = '';
            app.chanOn_9 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_9.Value = 'On';
            app.chanDisplay_9 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_9.Text = '';
            app.chanDisplay_9.Value = true;
            app.chanName_9 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_9.Value = 'ch8';
            app.chanOn_10 = uiswitch(app.ChannelsPanel, 'slider');
            app.chanOn_10.Value = 'On';
            app.chanDisplay_10 = uicheckbox(app.ChannelsPanel);
            app.chanDisplay_10.Text = '';
            app.chanDisplay_10.Value = true;
            app.chanName_10 = uieditfield(app.ChannelsPanel, 'text');
            app.chanName_10.Value = 'ch9';
            app.NumberofChannelsLabel = uilabel(app.ChannelsPanel);
            app.NumberofChannelsLabel.Text = 'Number of Channels:';
            app.NumberofChannelsEditField = uieditfield(app.ChannelsPanel, 'numeric');
            app.NumberofChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeNumChannelsFcn, true);
            app.NumberofChannelsEditField.Value = 10;
            app.TriggerControlsPanel = uipanel(app.UIFigure);
            app.TriggerControlsPanel.Title = 'Trigger Controls';
            app.TabGroup = uitabgroup(app.TriggerControlsPanel);
            app.ExtTrigTab = uitab(app.TabGroup);
            app.ExtTrigTab.Title = 'Ext Trig';
            app.ClickTrigTab = uitab(app.TabGroup);
            app.ClickTrigTab.Title = 'Click Trig';
            app.TriggerButton = uibutton(app.ClickTrigTab, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.TrigModeSwitch = uiswitch(app.TriggerControlsPanel, 'slider');
            app.TrigModeSwitch.Items = {'External', 'Click Button'};
            app.TrigModeSwitch.ItemsData = {'ExtTrigTab', 'ClickTrigTab'};
            app.TrigModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TrigModeChangedFcn, true);
            app.TrigModeSwitch.Value = 'ExtTrigTab';
            app.ArmDataAcquisitionPanel = uipanel(app.UIFigure);
            app.ArmDataAcquisitionPanel.Title = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.ArmDataAcquisitionPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.DAQsListBoxLabel = uilabel(app.UIFigure);
            app.DAQsListBoxLabel.Text = 'DAQs';
            app.DAQsListBox = uilistbox(app.UIFigure);
            app.DAQsListBox.Items = {};
            app.DAQsListBox.Multiselect = 'on';
            app.DAQsListBox.ValueChangedFcn = createCallbackFcn(app, @NewDAQFcn, true);
            app.DAQsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end